classdef ihcp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ihcpUIFigure                   matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SetcurrentvaluesasdefaultMenu  matlab.ui.container.Menu
        ResettodefaultMenu             matlab.ui.container.Menu
        MattLenahan2023Menu            matlab.ui.container.Menu
        PropertiesMenu                 matlab.ui.container.Menu
        EnforceMenu                    matlab.ui.container.Menu
        SamematerialMenu               matlab.ui.container.Menu
        samematerialUC                 matlab.ui.container.Menu
        samematerialCD                 matlab.ui.container.Menu
        PlotPanel                      matlab.ui.container.Panel
        plotCheck                      matlab.ui.control.CheckBox
        HeatfluxPanel                  matlab.ui.container.Panel
        NBmustusesamerLabel            matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        qCalculated                    matlab.ui.control.CheckBox
        ThermocouplesPanel             matlab.ui.container.Panel
        centreTC                       matlab.ui.control.CheckBox
        HeattransfercoefficientsButtonGroup  matlab.ui.container.ButtonGroup
        AverageButton                  matlab.ui.control.RadioButton
        IndividualButton               matlab.ui.control.RadioButton
        BrowseButton                   matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        epsilonEditField               matlab.ui.control.NumericEditField
        epsilonEditFieldLabel          matlab.ui.control.Label
        maxIterSpinner                 matlab.ui.control.Spinner
        maxIterSpinnerLabel            matlab.ui.control.Label
        RTOLerrorEditField             matlab.ui.control.NumericEditField
        RTOLerrorEditFieldLabel        matlab.ui.control.Label
        RTOLqhEditField                matlab.ui.control.NumericEditField
        RTOLqhEditFieldLabel           matlab.ui.control.Label
        hInitialEditField              matlab.ui.control.NumericEditField
        hInitialEditFieldLabel         matlab.ui.control.Label
        qInitialEditField              matlab.ui.control.NumericEditField
        qInitialEditFieldLabel         matlab.ui.control.Label
        rSpinner                       matlab.ui.control.Spinner
        rSpinnerLabel                  matlab.ui.control.Label
        DownstreamPanel                matlab.ui.container.Panel
        down2length                    matlab.ui.control.NumericEditField
        LengthEditField_7Label_2       matlab.ui.control.Label
        down2mat                       matlab.ui.control.DropDown
        MaterialDropDown_7Label_2      matlab.ui.control.Label
        downconv                       matlab.ui.control.Lamp
        ConvergentLamp_3Label          matlab.ui.control.Label
        downdt                         matlab.ui.control.NumericEditField
        dxLabel_6                      matlab.ui.control.Label
        downdx                         matlab.ui.control.NumericEditField
        dxLabel_5                      matlab.ui.control.Label
        down1length                    matlab.ui.control.NumericEditField
        LengthEditField_7Label         matlab.ui.control.Label
        down1mat                       matlab.ui.control.DropDown
        MaterialDropDown_7Label        matlab.ui.control.Label
        Section2Label_5                matlab.ui.control.Label
        Section1Label_4                matlab.ui.control.Label
        CentralPanel                   matlab.ui.container.Panel
        centTC                         matlab.ui.control.NumericEditField
        CentralTCEditFieldLabel        matlab.ui.control.Label
        cent3length                    matlab.ui.control.NumericEditField
        LengthEditFieldLabel_5         matlab.ui.control.Label
        cent3mat                       matlab.ui.control.DropDown
        MaterialDropDownLabel_5        matlab.ui.control.Label
        cent2length                    matlab.ui.control.NumericEditField
        LengthEditFieldLabel_4         matlab.ui.control.Label
        cent2mat                       matlab.ui.control.DropDown
        MaterialDropDownLabel_4        matlab.ui.control.Label
        cent1length                    matlab.ui.control.NumericEditField
        LengthEditFieldLabel_3         matlab.ui.control.Label
        cent1mat                       matlab.ui.control.DropDown
        MaterialDropDownLabel_3        matlab.ui.control.Label
        centconv                       matlab.ui.control.Lamp
        ConvergentLamp_2Label          matlab.ui.control.Label
        centdt                         matlab.ui.control.NumericEditField
        dxLabel_4                      matlab.ui.control.Label
        centdx                         matlab.ui.control.NumericEditField
        dxLabel_3                      matlab.ui.control.Label
        Section3Label                  matlab.ui.control.Label
        Section2Label_4                matlab.ui.control.Label
        Section1Label_3                matlab.ui.control.Label
        UpstreamPanel                  matlab.ui.container.Panel
        up1length                      matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        up2length                      matlab.ui.control.NumericEditField
        LengthEditFieldLabel_2         matlab.ui.control.Label
        up2mat                         matlab.ui.control.DropDown
        MaterialDropDownLabel_2        matlab.ui.control.Label
        upconv                         matlab.ui.control.Lamp
        ConvergentLampLabel            matlab.ui.control.Label
        updt                           matlab.ui.control.NumericEditField
        dxLabel_2                      matlab.ui.control.Label
        updx                           matlab.ui.control.NumericEditField
        dxLabel                        matlab.ui.control.Label
        up1mat                         matlab.ui.control.DropDown
        MaterialDropDownLabel          matlab.ui.control.Label
        Section2Label                  matlab.ui.control.Label
        Section1Label                  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        selectedfile                   matlab.ui.control.EditField
        SelectedfileEditFieldLabel     matlab.ui.control.Label
    end

    
    methods (Access = private)

        function checkconvergence(app)
            % lookup materials
            mat1u = materiallookup(app.up1mat.Value);
            mat2u = materiallookup(app.up2mat.Value);
            mat1c = materiallookup(app.cent1mat.Value);
            mat2c = materiallookup(app.cent2mat.Value);
            mat3c = materiallookup(app.cent3mat.Value);
            mat1d = materiallookup(app.down1mat.Value);
            mat2d = materiallookup(app.down2mat.Value);

            T = 25; % room temp deg C
            % calculate all tau
            % upstream
            k1u = mat1u(1, 1) + T*mat1u(1, 2) + T^2*mat1u(1, 3);
            c_p1u = mat1u(2, 1) + T*mat1u(2, 2) + T^2*mat1u(2, 3);
            rho1u = mat1u(3, 1) + T*mat1u(3, 2) + T^2*mat1u(3, 3);
            tau1u = k1u*rho1u^-1*c_p1u^-1*app.updt.Value...
                *app.updx.Value^-2;
            
            k2u = mat2u(1, 1) + T*mat2u(1, 2) + T^2*mat2u(1, 3);
            c_p2u = mat2u(2, 1) + T*mat2u(2, 2) + T^2*mat2u(2, 3);
            rho2u = mat2u(3, 1) + T*mat2u(3, 2) + T^2*mat2u(3, 3);
            tau2u = k2u*rho2u^-1*c_p2u^-1*app.updt.Value...
                *app.updx.Value^-2;

            if tau1u > .5 || tau2u > .5
                app.upconv.Color = [1, 0, 0]; % red
            else
                app.upconv.Color = [0, 1, 0]; % green
            end

            % central
            k1c = mat1c(1, 1) + T*mat1c(1, 2) + T^2*mat1c(1, 3);
            c_p1c = mat1c(2, 1) + T*mat1c(2, 2) + T^2*mat1c(2, 3);
            rho1c = mat1c(3, 1) + T*mat1c(3, 2) + T^2*mat1c(3, 3);
            tau1c = k1c*rho1c^-1*c_p1c^-1*app.centdt.Value...
                *app.centdx.Value^-2;

            k2c = mat2c(1, 1) + T*mat2c(1, 2) + T^2*mat2c(1, 3);
            c_p2c = mat2c(2, 1) + T*mat2c(2, 2) + T^2*mat2c(2, 3);
            rho2c = mat2c(3, 1) + T*mat2c(3, 2) + T^2*mat2c(3, 3);
            tau2c = k2c*rho2c^-1*c_p2c^-1*app.centdt.Value...
                *app.centdx.Value^-2;

            k3c = mat3c(1, 1) + T*mat3c(1, 2) + T^2*mat3c(1, 3);
            c_p3c = mat3c(2, 1) + T*mat3c(2, 2) + T^2*mat3c(2, 3);
            rho3c = mat3c(3, 1) + T*mat3c(3, 2) + T^2*mat3c(3, 3);
            tau3c = k3c*rho3c^-1*c_p3c^-1*app.centdt.Value...
                *app.centdx.Value^-2;

            if tau1c > .5 || tau2c > .5 || tau3c > .5
                app.centconv.Color = [1, 0, 0];
            else
                app.centconv.Color = [0, 1, 0];
            end

            % downstream
            k1d = mat1d(1, 1) + T*mat1d(1, 2) + T^2*mat1d(1, 3);
            c_p1d = mat1d(2, 1) + T*mat1d(2, 2) + T^2*mat1d(2, 3);
            rho1d = mat1d(3, 1) + T*mat1d(3, 2) + T^2*mat1d(3, 3);
            tau1d = k1d*rho1d^-1*c_p1d^-1*app.downdt.Value...
                *app.downdx.Value^-2;
            
            k2d = mat2d(1, 1) + T*mat2d(1, 2) + T^2*mat2d(1, 3);
            c_p2d = mat2d(2, 1) + T*mat2d(2, 2) + T^2*mat2d(2, 3);
            rho2d = mat2d(3, 1) + T*mat2d(3, 2) + T^2*mat2d(3, 3);
            tau2d = k2d*rho2d^-1*c_p2d^-1*app.downdt.Value...
                *app.downdx.Value^-2;

            if tau1d > .5 || tau2d > .5
                app.downconv.Color = [1, 0, 0];
            else
                app.downconv.Color = [0, 1, 0];
            end

            if any([tau1u tau1c tau1d tau2u tau2c tau2d tau3c] > 0.5)
                app.RunButton.Enable = "off";
            elseif not(isempty(app.selectedfile.Value)) % if file picked
                app.RunButton.Enable = "on";
            end

        end

        function getfile(app)
            % fix for lost focus
            f = figure('Position', [-100 -100 0 0]); % off-screen
            % file picker
            [file, path] = uigetfile({'*.*'; '*.csv'; '*.dat'; ...
                '*.txt'}, "Open...");
            delete(f);
            % if file selected i.e. user doesn't cancel
            if file ~= 0
                app.selectedfile.Value = fullfile(path, file);
                app.RunButton.Enable = "on";
                % prepare
                prepdata(fullfile(path, file))
            end
            % if matching .mat file exists
            if exist(fullfile(path, file)+".mat", "file") == 2
                app.qCalculated.Enable = "on";
            else
                app.qCalculated.Enable = "off";
                app.qCalculated.Value = 0;
            end
        end

        function savestate(app)
            state.up1mat.Value = app.up1mat.Value;
            state.up1length.Value = app.up1length.Value;
            state.up2mat.Value = app.up2mat.Value;
            state.up2length.Value = app.up2length.Value;
            state.updx.Value = app.updx.Value;
            state.updt.Value = app.updt.Value;

            state.cent1mat.Value = app.cent1mat.Value;
            state.cent1length.Value = app.cent1length.Value;
            state.cent2mat.Value = app.cent2mat.Value;
            state.cent2length.Value = app.cent2length.Value;
            state.centTC.Value = app.centTC.Value;
            state.cent3mat.Value = app.cent3mat.Value;
            state.cent3length.Value = app.cent3length.Value;
            state.centdx.Value = app.centdx.Value;
            state.centdt.Value = app.centdt.Value;

            state.down1mat.Value = app.down1mat.Value;
            state.down1length.Value = app.down1length.Value;
            state.down2mat.Value = app.down2mat.Value;
            state.down2length.Value = app.down2length.Value;
            state.downdx.Value = app.downdx.Value;
            state.downdt.Value = app.downdt.Value;

            state.rSpinner.Value = app.rSpinner.Value;
            state.epsilonEditField.Value = app.epsilonEditField.Value;
            state.qInitialEditField.Value = app.qInitialEditField.Value;
            state.hInitialEditField.Value = app.hInitialEditField.Value;
            state.RTOLqhEditField.Value = app.RTOLqhEditField.Value;
            state.RTOLerrorEditField.Value = app.RTOLerrorEditField.Value;
            state.maxIterSpinner.Value = app.maxIterSpinner.Value;

            state.plotCheck.Value = app.plotCheck.Value;
            state.centreTC.Value = app.centreTC.Value;
            state.AverageButton.Value = app.AverageButton.Value;
            state.IndividualButton.Value = app.IndividualButton.Value;
            state.qCalculated.Value = app.qCalculated.Value;

            save("defaultState.mat", "state")
        end

        function loadstate(app)
            load("defaultState.mat", "state")

            app.up1mat.Value = state.up1mat.Value;
            app.up1length.Value = state.up1length.Value;
            app.up2mat.Value = state.up2mat.Value;
            app.up2length.Value = state.up2length.Value;
            app.updx.Value = state.updx.Value;
            app.updt.Value = state.updt.Value;

            app.cent1mat.Value = state.cent1mat.Value;
            app.cent1length.Value = state.cent1length.Value;
            app.cent2mat.Value = state.cent2mat.Value;
            app.cent2length.Value = state.cent2length.Value;
            app.centTC.Value = state.centTC.Value;
            app.cent3mat.Value = state.cent3mat.Value;
            app.cent3length.Value = state.cent3length.Value;
            app.centdx.Value = state.centdx.Value;
            app.centdt.Value = state.centdt.Value;

            app.down1mat.Value = state.down1mat.Value;
            app.down1length.Value = state.down1length.Value;
            app.down2mat.Value = state.down2mat.Value;
            app.down2length.Value = state.down2length.Value;
            app.downdx.Value = state.downdx.Value;
            app.downdt.Value = state.downdt.Value;

            app.rSpinner.Value = state.rSpinner.Value;
            app.epsilonEditField.Value = state.epsilonEditField.Value;
            app.qInitialEditField.Value = state.qInitialEditField.Value;
            app.hInitialEditField.Value = state.hInitialEditField.Value;
            app.RTOLqhEditField.Value = state.RTOLqhEditField.Value;
            app.RTOLerrorEditField.Value = state.RTOLerrorEditField.Value;
            app.maxIterSpinner.Value = state.maxIterSpinner.Value;

            app.plotCheck.Value = state.plotCheck.Value;
            app.centreTC.Value = state.centreTC.Value;
            app.AverageButton.Value = state.AverageButton.Value;
            app.IndividualButton.Value = state.IndividualButton.Value;
            app.qCalculated.Value = state.qCalculated.Value;

            checkconvergence(app)
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % enforce same material between sections
            samematerialUCSelected(app)
            samematerialCDSelected(app)
            % button icon
            app.RunButton.Icon = fullfile(matlabroot,'ui','install',...
                'installer_login','images','toolstrip','Run_24.png');
            try loadstate(app)
            catch
                uialert(app.ihcpUIFigure, "No defaults found.", "Warning", Icon="warning")
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            getfile(app)
        end

        % Value changed function: updt, updx
        function updxValueChanged(app, event)
            % if upstream value is changed, downstream changes too
            app.downdx.Value = app.updx.Value;
            app.downdt.Value = app.updt.Value;
            % re-check convergence
            checkconvergence(app)
        end

        % Value changed function: centdt, centdx
        function centdxValueChanged(app, event)
            % re-check convergence
            checkconvergence(app)
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % gather all entered data
            % filename
            filename = app.selectedfile.Value;
            
            % geometry
            % upstream
            geom.up = cell(3, 1);
            geom.up{1} = app.updx.Value;
            geom.up{2} = [app.up1length.Value app.up2length.Value];
            geom.up{3} = [geom.up{2}(1)/geom.up{1},...
                geom.up{2}(2)/geom.up{1}];
            % central
            geom.cent = cell(4, 1);
            geom.cent{1} = app.centdx.Value;
            geom.cent{2} = [app.cent1length.Value app.cent2length.Value...
                app.cent3length.Value];
            geom.cent{3} = [geom.cent{2}(1)/geom.cent{1}... 
                geom.cent{2}(2)/geom.cent{1} geom.cent{2}(3)/geom.cent{1}];
            geom.cent{4} = app.centTC.Value;
            % downstream
            geom.down = cell(3, 1);
            geom.down{1} = app.downdx.Value;
            geom.down{2} = [app.down1length.Value app.down2length.Value];
            geom.down{3} = [geom.down{2}(1)/geom.down{1}...
                geom.down{2}(2)/geom.down{1}];
            
            % materials
            mat.up = cat(3, materiallookup(app.up1mat.Value),...
                materiallookup(app.up2mat.Value));
            mat.cent = cat(3, materiallookup(app.cent1mat.Value),...
                materiallookup(app.cent2mat.Value),...
                materiallookup(app.cent3mat.Value));
            mat.down = cat(3, materiallookup(app.down1mat.Value),...
                materiallookup(app.down2mat.Value));

            % parameters
            param.r = app.rSpinner.Value;
            param.epsilon = app.epsilonEditField.Value;
            param.qInitial = app.qInitialEditField.Value;
            param.hInitial = app.hInitialEditField.Value;
            param.maxiter = app.maxIterSpinner.Value;
            param.RTOLqh = app.RTOLqhEditField.Value;
            param.RTOLerror = app.RTOLerrorEditField.Value;

            % run upstreamdownstream.m
            upstreamdownstream(app, filename, geom, mat, param,...
                app.updt.Value)
            % run central.m
            central(app, filename, geom, mat, param, app.centdt.Value)
        end

        % Menu selected function: samematerialUC
        function samematerialUCSelected(app, event)
            if app.samematerialUC.Checked == "on"
                % toggle
                app.samematerialUC.Checked = "off";
                % same material not enforced, box editable
                app.cent1mat.Enable = "on";
            else
                % toggle
                app.samematerialUC.Checked = "on";
                % same material enforced, not editable & change value
                app.cent1mat.Enable = "off";
                app.cent1mat.Value = app.up2mat.Value;
            end
        end

        % Menu selected function: samematerialCD
        function samematerialCDSelected(app, event)
            if app.samematerialCD.Checked == "on"
                % toggle
                app.samematerialCD.Checked = "off";
                % same material not enforced, box enabled
                app.cent3mat.Enable = "on";
            else
                % toggle
                app.samematerialCD.Checked = "on";
                % same material enforced, box disabled
                app.cent3mat.Enable = "off";
                % update value
                app.cent3mat.Value = app.down1mat.Value;
            end
        end

        % Value changed function: up2mat
        function up2matValueChanged(app, event)
            if app.samematerialUC.Checked == "on"
                app.cent1mat.Value = app.up2mat.Value;
            end
            checkconvergence(app)
        end

        % Value changed function: down1mat
        function down1matValueChanged(app, event)
            if app.samematerialCD.Checked == "on"
                app.cent3mat.Value = app.down1mat.Value;
            end
            checkconvergence(app)
        end

        % Value changed function: cent1mat, cent2mat, cent3mat, down2mat, 
        % ...and 1 other component
        function up1matValueChanged(app, event)
            checkconvergence(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            getfile(app)
        end

        % Value changed function: centTC
        function centTCValueChanged(app, event)
            if app.centTC.Value > app.cent2length.Value
                app.centTC.Value = app.cent2length.Value;
            end
        end

        % Value changed function: centreTC
        function centreTCValueChanged(app, event)
            if app.centreTC.Value == 1
                app.IndividualButton.Enable = "on";
                app.centTC.Enable = "on";
            else    
                app.IndividualButton.Enable = "off";
                app.AverageButton.Value = 1;
                app.centTC.Enable = "off";
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % if matching .mat file exists
            if exist(app.selectedfile.Value+".mat", "file") == 2
                app.qCalculated.Enable = "on";
            else
                app.qCalculated.Enable = "off";
                app.qCalculated.Value = 0;
            end
        end

        % Menu selected function: SetcurrentvaluesasdefaultMenu
        function SetcurrentvaluesasdefaultMenuSelected(app, event)
            savestate(app)
        end

        % Menu selected function: ResettodefaultMenu
        function ResettodefaultMenuSelected(app, event)
            loadstate(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ihcpUIFigure = uifigure('Visible', 'off');
            app.ihcpUIFigure.Name = 'ihcp';
            app.FileMenu = uimenu(app.ihcpUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.SetcurrentvaluesasdefaultMenu = uimenu(app.FileMenu);
            app.SetcurrentvaluesasdefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @SetcurrentvaluesasdefaultMenuSelected, true);
            app.SetcurrentvaluesasdefaultMenu.Accelerator = 'd';
            app.SetcurrentvaluesasdefaultMenu.Text = 'Set current values as default';
            app.ResettodefaultMenu = uimenu(app.FileMenu);
            app.ResettodefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettodefaultMenuSelected, true);
            app.ResettodefaultMenu.Accelerator = 'r';
            app.ResettodefaultMenu.Text = 'Reset to default';
            app.MattLenahan2023Menu = uimenu(app.FileMenu);
            app.MattLenahan2023Menu.Enable = 'off';
            app.MattLenahan2023Menu.Text = 'Matt Lenahan 2023';
            app.PropertiesMenu = uimenu(app.ihcpUIFigure);
            app.PropertiesMenu.Text = 'Properties';
            app.EnforceMenu = uimenu(app.PropertiesMenu);
            app.EnforceMenu.Text = 'Enforce...';
            app.SamematerialMenu = uimenu(app.EnforceMenu);
            app.SamematerialMenu.Text = 'Same material...';
            app.samematerialUC = uimenu(app.SamematerialMenu);
            app.samematerialUC.MenuSelectedFcn = createCallbackFcn(app, @samematerialUCSelected, true);
            app.samematerialUC.Text = 'Upstream-central';
            app.samematerialCD = uimenu(app.SamematerialMenu);
            app.samematerialCD.MenuSelectedFcn = createCallbackFcn(app, @samematerialCDSelected, true);
            app.samematerialCD.Text = 'Central-downstream';
            app.SelectedfileEditFieldLabel = uilabel(app.ihcpUIFigure);
            app.SelectedfileEditFieldLabel.Text = 'Selected file';
            app.selectedfile = uieditfield(app.ihcpUIFigure, 'text');
            app.selectedfile.Editable = 'off';
            app.selectedfile.Placeholder = '<none>';
            app.RunButton = uibutton(app.ihcpUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.UpstreamPanel = uipanel(app.ihcpUIFigure);
            app.UpstreamPanel.Title = 'Upstream';
            app.Section1Label = uilabel(app.UpstreamPanel);
            app.Section1Label.Text = 'Section 1';
            app.Section2Label = uilabel(app.UpstreamPanel);
            app.Section2Label.Text = 'Section 2';
            app.MaterialDropDownLabel = uilabel(app.UpstreamPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.up1mat = uidropdown(app.UpstreamPanel);
            app.up1mat.Items = {'Copper', 'Inconel 718', 'Haynes 25'};
            app.up1mat.ValueChangedFcn = createCallbackFcn(app, @up1matValueChanged, true);
            app.up1mat.Value = 'Copper';
            app.dxLabel = uilabel(app.UpstreamPanel);
            app.dxLabel.Text = 'dx';
            app.updx = uieditfield(app.UpstreamPanel, 'numeric');
            app.updx.Limits = [0 Inf];
            app.updx.ValueChangedFcn = createCallbackFcn(app, @updxValueChanged, true);
            app.updx.Value = 0.00025;
            app.dxLabel_2 = uilabel(app.UpstreamPanel);
            app.dxLabel_2.Text = 'dt';
            app.updt = uieditfield(app.UpstreamPanel, 'numeric');
            app.updt.Limits = [0 Inf];
            app.updt.ValueChangedFcn = createCallbackFcn(app, @updxValueChanged, true);
            app.updt.Value = 0.00025;
            app.ConvergentLampLabel = uilabel(app.UpstreamPanel);
            app.ConvergentLampLabel.Text = 'Convergent';
            app.upconv = uilamp(app.UpstreamPanel);
            app.MaterialDropDownLabel_2 = uilabel(app.UpstreamPanel);
            app.MaterialDropDownLabel_2.Text = 'Material';
            app.up2mat = uidropdown(app.UpstreamPanel);
            app.up2mat.Items = {'Copper', 'Inconel 718', 'Haynes 25'};
            app.up2mat.ValueChangedFcn = createCallbackFcn(app, @up2matValueChanged, true);
            app.up2mat.Value = 'Inconel 718';
            app.LengthEditFieldLabel_2 = uilabel(app.UpstreamPanel);
            app.LengthEditFieldLabel_2.Text = 'Length';
            app.up2length = uieditfield(app.UpstreamPanel, 'numeric');
            app.up2length.Limits = [0 Inf];
            app.up2length.Value = 0.0065;
            app.LengthEditFieldLabel = uilabel(app.UpstreamPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.up1length = uieditfield(app.UpstreamPanel, 'numeric');
            app.up1length.Limits = [0 Inf];
            app.up1length.Value = 0.0015;
            app.CentralPanel = uipanel(app.ihcpUIFigure);
            app.CentralPanel.Title = 'Central';
            app.Section1Label_3 = uilabel(app.CentralPanel);
            app.Section1Label_3.Text = 'Section 1';
            app.Section2Label_4 = uilabel(app.CentralPanel);
            app.Section2Label_4.Text = 'Section 2';
            app.Section3Label = uilabel(app.CentralPanel);
            app.Section3Label.Text = 'Section 3';
            app.dxLabel_3 = uilabel(app.CentralPanel);
            app.dxLabel_3.Text = 'dx';
            app.centdx = uieditfield(app.CentralPanel, 'numeric');
            app.centdx.Limits = [0 Inf];
            app.centdx.ValueChangedFcn = createCallbackFcn(app, @centdxValueChanged, true);
            app.centdx.Value = 0.0001;
            app.dxLabel_4 = uilabel(app.CentralPanel);
            app.dxLabel_4.Text = 'dt';
            app.centdt = uieditfield(app.CentralPanel, 'numeric');
            app.centdt.Limits = [0 Inf];
            app.centdt.ValueChangedFcn = createCallbackFcn(app, @centdxValueChanged, true);
            app.centdt.Value = 0.00125;
            app.ConvergentLamp_2Label = uilabel(app.CentralPanel);
            app.ConvergentLamp_2Label.Text = 'Convergent';
            app.centconv = uilamp(app.CentralPanel);
            app.MaterialDropDownLabel_3 = uilabel(app.CentralPanel);
            app.MaterialDropDownLabel_3.Text = 'Material';
            app.cent1mat = uidropdown(app.CentralPanel);
            app.cent1mat.Items = {'Copper', 'Inconel 718', 'Haynes 25'};
            app.cent1mat.ValueChangedFcn = createCallbackFcn(app, @up1matValueChanged, true);
            app.cent1mat.Value = 'Inconel 718';
            app.LengthEditFieldLabel_3 = uilabel(app.CentralPanel);
            app.LengthEditFieldLabel_3.Text = 'Length';
            app.cent1length = uieditfield(app.CentralPanel, 'numeric');
            app.cent1length.Limits = [0 Inf];
            app.cent1length.Value = 0.0015;
            app.MaterialDropDownLabel_4 = uilabel(app.CentralPanel);
            app.MaterialDropDownLabel_4.Text = 'Material';
            app.cent2mat = uidropdown(app.CentralPanel);
            app.cent2mat.Items = {'Copper', 'Inconel 718', 'Haynes 25'};
            app.cent2mat.ValueChangedFcn = createCallbackFcn(app, @up1matValueChanged, true);
            app.cent2mat.Value = 'Haynes 25';
            app.LengthEditFieldLabel_4 = uilabel(app.CentralPanel);
            app.LengthEditFieldLabel_4.Text = 'Length';
            app.cent2length = uieditfield(app.CentralPanel, 'numeric');
            app.cent2length.Limits = [0 Inf];
            app.cent2length.Value = 0.005;
            app.MaterialDropDownLabel_5 = uilabel(app.CentralPanel);
            app.MaterialDropDownLabel_5.Text = 'Material';
            app.cent3mat = uidropdown(app.CentralPanel);
            app.cent3mat.Items = {'Copper', 'Inconel 718', 'Haynes 25'};
            app.cent3mat.ValueChangedFcn = createCallbackFcn(app, @up1matValueChanged, true);
            app.cent3mat.Value = 'Inconel 718';
            app.LengthEditFieldLabel_5 = uilabel(app.CentralPanel);
            app.LengthEditFieldLabel_5.Text = 'Length';
            app.cent3length = uieditfield(app.CentralPanel, 'numeric');
            app.cent3length.Limits = [0 Inf];
            app.cent3length.Value = 0.0015;
            app.CentralTCEditFieldLabel = uilabel(app.CentralPanel);
            app.CentralTCEditFieldLabel.Text = 'Central TC';
            app.centTC = uieditfield(app.CentralPanel, 'numeric');
            app.centTC.Limits = [0 Inf];
            app.centTC.ValueChangedFcn = createCallbackFcn(app, @centTCValueChanged, true);
            app.centTC.Tooltip = {'Distance into the central piece of material, from the left edge'};
            app.centTC.Value = 0.0015;
            app.DownstreamPanel = uipanel(app.ihcpUIFigure);
            app.DownstreamPanel.Title = 'Downstream';
            app.Section1Label_4 = uilabel(app.DownstreamPanel);
            app.Section1Label_4.Text = 'Section 1';
            app.Section2Label_5 = uilabel(app.DownstreamPanel);
            app.Section2Label_5.Text = 'Section 2';
            app.MaterialDropDown_7Label = uilabel(app.DownstreamPanel);
            app.MaterialDropDown_7Label.Text = 'Material';
            app.down1mat = uidropdown(app.DownstreamPanel);
            app.down1mat.Items = {'Copper', 'Inconel 718', 'Haynes 25'};
            app.down1mat.ValueChangedFcn = createCallbackFcn(app, @down1matValueChanged, true);
            app.down1mat.Value = 'Inconel 718';
            app.LengthEditField_7Label = uilabel(app.DownstreamPanel);
            app.LengthEditField_7Label.Text = 'Length';
            app.down1length = uieditfield(app.DownstreamPanel, 'numeric');
            app.down1length.Limits = [0 Inf];
            app.down1length.Value = 0.0065;
            app.dxLabel_5 = uilabel(app.DownstreamPanel);
            app.dxLabel_5.Enable = 'off';
            app.dxLabel_5.Text = 'dx';
            app.downdx = uieditfield(app.DownstreamPanel, 'numeric');
            app.downdx.Limits = [0 Inf];
            app.downdx.Enable = 'off';
            app.downdx.Value = 0.00025;
            app.dxLabel_6 = uilabel(app.DownstreamPanel);
            app.dxLabel_6.Enable = 'off';
            app.dxLabel_6.Text = 'dt';
            app.downdt = uieditfield(app.DownstreamPanel, 'numeric');
            app.downdt.Limits = [0 Inf];
            app.downdt.Enable = 'off';
            app.downdt.Value = 0.00025;
            app.ConvergentLamp_3Label = uilabel(app.DownstreamPanel);
            app.ConvergentLamp_3Label.Text = 'Convergent';
            app.downconv = uilamp(app.DownstreamPanel);
            app.MaterialDropDown_7Label_2 = uilabel(app.DownstreamPanel);
            app.MaterialDropDown_7Label_2.Text = 'Material';
            app.down2mat = uidropdown(app.DownstreamPanel);
            app.down2mat.Items = {'Copper', 'Inconel 718', 'Haynes 25'};
            app.down2mat.ValueChangedFcn = createCallbackFcn(app, @up1matValueChanged, true);
            app.down2mat.Value = 'Copper';
            app.LengthEditField_7Label_2 = uilabel(app.DownstreamPanel);
            app.LengthEditField_7Label_2.Text = 'Length';
            app.down2length = uieditfield(app.DownstreamPanel, 'numeric');
            app.down2length.Limits = [0 Inf];
            app.down2length.Value = 0.0015;
            app.ParametersPanel = uipanel(app.ihcpUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.rSpinnerLabel = uilabel(app.ParametersPanel);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.ParametersPanel);
            app.rSpinner.Limits = [1 50];
            app.rSpinner.Value = 5;
            app.qInitialEditFieldLabel = uilabel(app.ParametersPanel);
            app.qInitialEditFieldLabel.Text = 'qInitial';
            app.qInitialEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.qInitialEditField.Limits = [1 Inf];
            app.qInitialEditField.Value = 1000;
            app.hInitialEditFieldLabel = uilabel(app.ParametersPanel);
            app.hInitialEditFieldLabel.Text = 'hInitial';
            app.hInitialEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.hInitialEditField.Limits = [1 Inf];
            app.hInitialEditField.Value = 1000;
            app.RTOLqhEditFieldLabel = uilabel(app.ParametersPanel);
            app.RTOLqhEditFieldLabel.Text = 'RTOLq/h';
            app.RTOLqhEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RTOLqhEditField.Limits = [0 Inf];
            app.RTOLqhEditField.Value = 0.001;
            app.RTOLerrorEditFieldLabel = uilabel(app.ParametersPanel);
            app.RTOLerrorEditFieldLabel.Text = 'RTOLerror';
            app.RTOLerrorEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RTOLerrorEditField.Limits = [0 Inf];
            app.RTOLerrorEditField.Value = 1e-06;
            app.maxIterSpinnerLabel = uilabel(app.ParametersPanel);
            app.maxIterSpinnerLabel.Text = 'maxIter';
            app.maxIterSpinner = uispinner(app.ParametersPanel);
            app.maxIterSpinner.Limits = [1 50];
            app.maxIterSpinner.Value = 20;
            app.epsilonEditFieldLabel = uilabel(app.ParametersPanel);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.epsilonEditField.Limits = [0 Inf];
            app.epsilonEditField.Value = 0.01;
            app.BrowseButton = uibutton(app.ihcpUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.HeattransfercoefficientsButtonGroup = uibuttongroup(app.ihcpUIFigure);
            app.HeattransfercoefficientsButtonGroup.Title = 'Heat transfer coefficients';
            app.IndividualButton = uiradiobutton(app.HeattransfercoefficientsButtonGroup);
            app.IndividualButton.Enable = 'off';
            app.IndividualButton.Text = 'Individual';
            app.AverageButton = uiradiobutton(app.HeattransfercoefficientsButtonGroup);
            app.AverageButton.Text = 'Average';
            app.AverageButton.Value = true;
            app.ThermocouplesPanel = uipanel(app.ihcpUIFigure);
            app.ThermocouplesPanel.Enable = 'off';
            app.ThermocouplesPanel.Title = 'Thermocouples';
            app.centreTC = uicheckbox(app.ThermocouplesPanel);
            app.centreTC.ValueChangedFcn = createCallbackFcn(app, @centreTCValueChanged, true);
            app.centreTC.Text = 'Use TC in central piece';
            app.HeatfluxPanel = uipanel(app.ihcpUIFigure);
            app.HeatfluxPanel.Title = 'Heat flux';
            app.qCalculated = uicheckbox(app.HeatfluxPanel);
            app.qCalculated.Enable = 'off';
            app.qCalculated.Text = 'Use existing q';
            app.RefreshButton = uibutton(app.HeatfluxPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.NBmustusesamerLabel = uilabel(app.HeatfluxPanel);
            app.NBmustusesamerLabel.Text = 'N.B. must use same r';
            app.PlotPanel = uipanel(app.ihcpUIFigure);
            app.PlotPanel.Title = 'Plot';
            app.plotCheck = uicheckbox(app.PlotPanel);
            app.plotCheck.Text = 'Generate plot on exit';
            % Show the figure after all components are created
            app.ihcpUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ihcp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ihcpUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ihcpUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ihcpUIFigure)
        end
    end
end